classdef volt_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoltAppUIFigure              matlab.ui.Figure
        HighpassButtonGroup          matlab.ui.container.ButtonGroup
        fastsmoothButton             matlab.ui.control.RadioButton
        highpassButton               matlab.ui.control.RadioButton
        ScreenshotButton             matlab.ui.control.Button
        windowsecondsEditField       matlab.ui.control.NumericEditField
        windowsecondsEditFieldLabel  matlab.ui.control.Label
        recalculateDeltaFFButton     matlab.ui.control.Button
        viewtimeintervalSlider       matlab.ui.control.RangeSlider
        Panel                        matlab.ui.container.Panel
        SpikesSwitch                 matlab.ui.control.ToggleSwitch
        ROIsListBox                  matlab.ui.control.ListBox
        ROIsListBoxLabel             matlab.ui.control.Label
        FolderpathEditField          matlab.ui.control.EditField
        FolderpathEditFieldLabel     matlab.ui.control.Label
        RestoreintervalButton        matlab.ui.control.Button
        RunanalysisButton            matlab.ui.control.Button
        AddintervaltoignoreButton    matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        FindfolderButton             matlab.ui.control.Button
        SaveanalysisButton           matlab.ui.control.Button
        UIAxes_estimate_DFF          matlab.ui.control.UIAxes
        UIAxes_Fdet                  matlab.ui.control.UIAxes
        UIAxes_DFF_AP                matlab.ui.control.UIAxes
        UIAxes_highpass_traces       matlab.ui.control.UIAxes
        UIAxes_SNR_AP                matlab.ui.control.UIAxes
        UIAxes_SNR_traces            matlab.ui.control.UIAxes
        UIAxes_image                 matlab.ui.control.UIAxes
        UIAxes_Fraw                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        % TODO
        % make sure SNR scatter is accurate - seems lower than actuality
        % (though it starts at the right baseline)
        
        % save plots - create multi-axis plots for all
        % make app for reloading prior analysis?

        datadir   % directory of data
        titlestr  % name of data
        dat       % structure with original data
        F_proc    % structure for processed data
        lines_raw % handles for lines in raw F plot
        roicolors % for color consistency between plot

        lines_SNR
        scatter_SNR
        lines_hp
        scatter_DFF
        spikes_SNR
        spikes_hp
        lines_det
        spikes_det
        ylims_fraw

        % spikes_SNR_forscatterplot
        % spikes_DFF_forscatterplot

        
        lines_est_dff
        spikes_est_dff
        
        dff_window = 3;
        highpass_movfilter = 0;


        bg
        gain

        t_intervals = [];

        shadings
        shading_lines

        savedir = '\\ad\eng\users\s\s\sshayk\My Documents\MATLAB\analyze_voltage\analysis';

    end
    
    methods (Access = private)
              
        function [] = clear_data(app)
            
            app.ROIsListBox.Items = {};
            app.ROIsListBox.Value = {};
            cla([app.UIAxes_Fraw, app.UIAxes_image, app.UIAxes_DFF_AP, app.UIAxes_highpass_traces, app.UIAxes_Fdet, app.UIAxes_SNR_AP, app.UIAxes_SNR_traces, app.UIAxes_estimate_DFF])
            app.SpikesSwitch.ValueIndex = 1;

            app.datadir = [];   % directory of data
            app.titlestr = [];  % name of data
            app.dat = [];       % structure with original data
            app.F_proc = [];    % structure for processed data
        
            app.lines_raw = []; 
            app.lines_SNR = [];
            app.scatter_SNR = [];
            app.lines_hp = [];
            app.scatter_DFF = [];
            app.spikes_SNR = [];
            app.spikes_hp = [];
            app.lines_det = [];
            app.spikes_det = [];
    
            app.t_intervals = [];

            delete(app.shadings)
            delete(app.shading_lines)
            app.shadings = [];
            app.shading_lines = [];


            app.UIAxes_Fraw.Visible = 'off';
            app.UIAxes_Fdet.Visible = 'off';
            app.UIAxes_image.Visible = 'off';
            app.UIAxes_image.Title.Visible = 'off';
            app.UIAxes_DFF_AP.Visible = 'off';
            app.UIAxes_highpass_traces.Visible = 'off';
            app.UIAxes_SNR_AP.Visible = 'off';
            app.UIAxes_SNR_traces.Visible = 'off';
            app.ScreenshotButton.Visible = 'off';
            app.ROIsListBox.Visible = 'off';
            app.RunanalysisButton.Visible = 'off';
            app.SaveanalysisButton.Visible = 'off';
            app.Panel.Visible = 'off';
            app.AddintervaltoignoreButton.Visible  = 'off';
            app.RestoreintervalButton.Visible = 'off';
            app.viewtimeintervalSlider.Visible = 'off';
            app.recalculateDeltaFFButton.Visible  = 'off';
            app.windowsecondsEditField.Visible = 'off';
            app.windowsecondsEditFieldLabel.Visible = 'off';
            app.UIAxes_estimate_DFF.Visible = 'off';
            app.HighpassButtonGroup.Visible = 'off';

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VoltAppUIFigure.Name = 'VoltApp';
            addpath(genpath('/net/engnas/Users/s/s/sshayk/My Documents/MATLAB/analyze_voltage'))
            % addpath(genpath('\\ad\eng\users\s\s\sshayk\My Documents\MATLAB\analyze_voltage'))

            addpath '/net/engnas/Users/s/s/sshayk/My Documents/MATLAB/utilities'
            addpath '\\engnas.bu.edu\users\s\s\sshayk\My Documents\MATLAB\utilities'
            warning('off', 'all');
            clear_data(app);
        end

        % Value changed function: FolderpathEditField
        function FolderpathEditFieldValueChanged(app, event)
            app.datadir = app.FolderpathEditField.Value;
        end

        % Button pushed function: FindfolderButton
        function FindfolderButtonPushed(app, event)
            app.FolderpathEditField.Value = uigetdir("U:\eng_research_economo2\SFS\TICO2\");
            app.datadir  = app.FolderpathEditField.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            clear_data(app);
            app.datadir = app.FolderpathEditField.Value;

            datastr = strrep(app.datadir,'\\ad\eng\research\','U:\');
            
            strparts = strsplit(datastr,{'/','\'});
            app.titlestr = [strparts{end-2},'/',strparts{end-3},'/',strparts{end-1},'/',strparts{end}(10:end)];
            app.dat.signal = load(fullfile(datastr,'signal.mat'));
            app.dat.rois = load(fullfile(datastr,'rois.mat'));
            app.dat.nframes = size(app.dat.signal.tr,2);
            app.dat.params.fs = 800;
            app.dat.tvec = (1:app.dat.nframes)*(1/app.dat.params.fs);
            app.dat.params.k_valid = true(size(app.dat.tvec));
            app.dat.params.k_valid(app.dat.tvec<1) = false;
            app.dat.params.k_valid(app.dat.tvec>(app.dat.tvec(end) - 1)) = false;

            app.spikes_est_dff =[];
            app.lines_est_dff =[];

            % get F values
            app.F_proc = process_voltage(app.dat.signal.tr);
          
            NR = size(app.dat.rois.roimat,3);

            app.UIAxes_Fraw.Visible = 'on';
            hold(app.UIAxes_Fraw,'on')
            colororder("gem12")
            for nr = 1:NR
                app.lines_raw{nr} = plot(app.UIAxes_Fraw,app.dat.tvec,-app.dat.signal.tr(nr,:),'DisplayName',['ROI ',num2str(nr)]);
                app.roicolors{nr} = get(app.lines_raw{nr},'Color');
            end
            legend(app.UIAxes_Fraw,'Location','eastoutside','Box','off')
            
            xlabel(app.UIAxes_Fraw,'time (s)'), ylabel(app.UIAxes_Fraw,'-F')
            legend(app.UIAxes_Fraw)

            app.ylims_fraw = ylim(app.UIAxes_Fraw);
           
            for nr = 1:NR
                app.ROIsListBox.Items{nr} = ['ROI ', num2str(nr)];
                % addStyle(app.ROIsListBox, uistyle("FontColor", app.roicolors{nr}), "item", nr)
            end
            app.ROIsListBox.Value = app.ROIsListBox.Items;
            app.ROIsListBox.Visible = 'on';

            app.dat.params.roi_use = 1:NR;

            imfile = [strparts{end}(10:end),'.raw'];
            reader = FrameReader(fullfile(strparts{1:end-1},imfile));

            load(fullfile(datastr,'mask.mat'))
            load(fullfile(datastr,'rois.mat'))
            load(fullfile(datastr,'translation.mat'))
    
            im = double(reader.getFrames(100));
    
            translation = gather(translation);

            if size(translation,1)>=size(im,3)
            for k = 1:size(im,3)
                im_t(:,:,k) = circshift(im(:,:,k), translation(k,:));
            end
            else
                im_t = im;
            end
            im_av = mean(im_t,3);
        
            cents = zeros(2,NR);
            for nr = 1:NR
                cents(:,nr) = gray_centroid(roimat(:,:,nr));
            end
            
            if strcmp(reader.dtype,'uint16')
                app.bg = 100;
            else
                app.bg = 20;
            end
            imagesc(app.UIAxes_image,im_av-app.bg)
            axis(app.UIAxes_image,'image') 
            colormap(app.UIAxes_image,'gray')
            axis(app.UIAxes_image,'off') 
            title(app.UIAxes_image,escapeTexChars(app.titlestr))
            app.UIAxes_image.Title.Visible = 'on';
            
            for nr = 1:NR
                text(app.UIAxes_image,cents(2,nr), cents(1,nr)-30,num2str(nr),'Color',app.roicolors{nr})
            end
            
            
            if app.bg == 100 % for sensitivity mode
                app.gain = 0.25; 
            else
                app.gain = 1.15;
            end 
            phot_im = (im_av - app.bg)*app.gain;
            app.dat.cell_phot_singleframe = zeros(1,size(app.dat.rois.roimat,3));
            app.dat.pixcount = zeros(1,size(app.dat.rois.roimat,3));
            for kr = 1:size(app.dat.rois.roimat,3)
                    app.dat.cell_phot_singleframe (kr) = sum(phot_im(find(app.dat.rois.roimat(:,:,kr))));
                app.dat.pixcount(kr) = nnz(app.dat.rois.roimat(:,:,kr));
            end

            app.AddintervaltoignoreButton.Visible  = 'on';
            app.RestoreintervalButton.Visible = 'on';

            app.viewtimeintervalSlider.Visible = 'on';
            app.RunanalysisButton.Visible = 'on';
            app.ResetButton.Visible = 'on';
             % app.HighpassButtonGroup.Visible = 'on';
           
            app.viewtimeintervalSlider.Limits = [0 max(app.dat.tvec)];
            app.viewtimeintervalSlider.Value= [min(app.dat.tvec) max(app.dat.tvec)];
            viewtimeintervalSliderValueChanged(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.clear_data();
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)

            k_r = ismember(app.ROIsListBox.Items, app.ROIsListBox.Value);
            
            spikesvalue = app.SpikesSwitch.ValueIndex;
            opts = {'off','on'};

            NR = length(app.ROIsListBox.Items);
            for nr = 1:NR
                if k_r(nr)
                     app.lines_raw{nr}.HandleVisibility = 'on';
                     app.lines_raw{nr}.Visible = 'on';
                     app.lines_hp{nr}.Visible = 'on';
                     app.lines_SNR{nr}.Visible = 'on';
                     app.scatter_DFF{nr}.MarkerFaceAlpha = 1;
                     app.scatter_SNR{nr}.MarkerFaceAlpha = 1;
                     app.lines_det{nr}.Visible = 'on';
                     app.spikes_hp{nr}.Visible = opts{spikesvalue};
                     app.spikes_SNR{nr}.Visible = opts{spikesvalue};
                     app.spikes_det{nr}.Visible = opts{spikesvalue};
                     app.lines_est_dff{nr}.Visible = 'on';
                     app.spikes_est_dff{nr}.Visible =  opts{spikesvalue};
                 else
                     app.lines_raw{nr}.HandleVisibility = 'off';
                     app.lines_raw{nr}.Visible = 'off';
                     app.lines_hp{nr}.Visible = 'off';
                     app.lines_SNR{nr}.Visible = 'off';
                     app.scatter_SNR{nr}.MarkerFaceAlpha = 0.15;
                     app.scatter_DFF{nr}.MarkerFaceAlpha = 0.15;
                     app.lines_det{nr}.Visible = 'off';
                     app.spikes_hp{nr}.Visible = 'off';
                     app.spikes_SNR{nr}.Visible = 'off';
                     app.spikes_det{nr}.Visible = 'off';
                     app.lines_est_dff{nr}.Visible = 'off';
                     app.spikes_est_dff{nr}.Visible = 'off';
                end
            end
        end

        % Button pushed function: AddintervaltoignoreButton
        function AddintervaltoignoreButtonPushed(app, event)
            [newintervals, newshadings, newshadinglines] = select1Interval(app.UIAxes_Fraw, app.ylims_fraw);
            app.t_intervals = [app.t_intervals;newintervals];
            app.shadings = [app.shadings;newshadings]; 
            if ~isempty(app.shading_lines) && ~isvalid(app.shading_lines(1)) 
                app.shading_lines = newshadinglines;
            else
            app.shading_lines = [app.shading_lines;newshadinglines]; 
            end
        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            tvec = app.dat.tvec;

            app.F_proc = process_voltage(app.dat.signal.tr,'highpasstype',logical(app.highpass_movfilter));


            % reset as necessary
            app.dat.params.k_valid = true(size(app.dat.tvec));
            app.dat.params.k_valid(app.dat.tvec<1) = false;
            app.dat.params.k_valid(app.dat.tvec>(app.dat.tvec(end) - 1)) = false;
            cla([app.UIAxes_DFF_AP, app.UIAxes_highpass_traces, app.UIAxes_Fdet, app.UIAxes_SNR_AP, app.UIAxes_SNR_traces, app.UIAxes_estimate_DFF])


            app.UIAxes_Fdet.Visible = 'on';
            app.UIAxes_image.Visible = 'on';
            app.UIAxes_DFF_AP.Visible = 'on';
            app.UIAxes_highpass_traces.Visible = 'on';
            app.UIAxes_SNR_AP.Visible = 'on';
            app.UIAxes_SNR_traces.Visible = 'on';
            app.UIAxes_estimate_DFF.Visible = 'on';
            app.SaveanalysisButton.Visible = 'on';
            app.Panel.Visible = 'on';
            app.recalculateDeltaFFButton.Visible  = 'on';
            app.windowsecondsEditField.Visible = 'on';
            app.windowsecondsEditFieldLabel.Visible = 'on';
            app.ScreenshotButton.Visible = 'on';

            for nk = 1:size(app.t_intervals,1)
                cur_int = app.t_intervals(nk,:);
                app.dat.params.k_valid(tvec<=max(cur_int) & tvec>=min(cur_int)) = false;
            end

             NR = size(app.dat.rois.roimat,3);

            [app.F_proc.F_AP, app.F_proc.params_AP] = calc_APs(app.F_proc.F_det);
            % get spikes
            [app.F_proc.t_s, app.F_proc.parameters_kspikes] = get_spike_locations(app.F_proc.F_det, app.F_proc.F_AP, app.F_proc.N_f, app.F_proc.dF_ur, app.F_proc.dF_dr); 
            % get Vm and other baseline calculations
            [app.F_proc.F_sub, app.F_proc.F_nospike, app.F_proc.F_0, app.F_proc.params_baselines] = get_subthreshold_trace(app.dat.signal.tr, app.F_proc.t_s);


            app.dat.snr_trace = app.F_proc.F_det./app.F_proc.N_f;
            app.dat.snr_AP = app.F_proc.F_AP./app.F_proc.N_f; % only valid at detected spikes

            app.dat.dff_trace = -(app.dat.signal.tr-app.F_proc.F_0)./app.F_proc.F_0;
            app.dat.dff_AP = app.F_proc.F_AP./app.F_proc.F_0; % only valid at detected spikes

            t_s_cur = app.F_proc.t_s(:,app.dat.params.k_valid);
            tcur = app.dat.tvec(app.dat.params.k_valid);

            hold(app.UIAxes_SNR_AP,'on');
            SNR_cur = app.dat.snr_AP(:,app.dat.params.k_valid);
            for nr = 1:NR
                if nnz(t_s_cur(nr,:))
                    app.scatter_SNR{nr} = scatter(app.UIAxes_SNR_AP,nr*ones(1,nnz(t_s_cur(nr,:))),SNR_cur(nr,(t_s_cur(nr,:))) ,30,'filled','MarkerFaceColor',app.roicolors{nr});
                else
                    app.scatter_SNR{nr} = scatter(app.UIAxes_SNR_AP,nr, NaN);
                end
            end
            xlim(app.UIAxes_SNR_AP,[0 NR+1]),xticks(app.UIAxes_SNR_AP,1:NR)
            ylim(app.UIAxes_SNR_AP,[4 max([max(ylim(app.UIAxes_SNR_AP)),5])])

            hold(app.UIAxes_DFF_AP,'on');
            DFF_cur = app.dat.dff_AP(:,app.dat.params.k_valid);
            for nr = 1:NR
                if nnz(t_s_cur(nr,:))
                    app.scatter_DFF{nr} = scatter(app.UIAxes_DFF_AP,nr*ones(1,nnz(t_s_cur(nr,:))), DFF_cur(nr,(t_s_cur(nr,:))),30,'filled','MarkerFaceColor',app.roicolors{nr});
                else
                    app.scatter_DFF{nr} = scatter(app.UIAxes_DFF_AP,nr, NaN);
                end
            end
            xlim(app.UIAxes_DFF_AP,[0 NR+1]), xticks(app.UIAxes_DFF_AP,1:NR)
            ylim(app.UIAxes_DFF_AP,[0 max(ylim(app.UIAxes_DFF_AP))])

           hold(app.UIAxes_SNR_traces,'on');
           for nr = 1:NR
              snr_cur = app.dat.snr_trace(nr,app.dat.params.k_valid,1);
                app.lines_SNR{nr} = plot(app.UIAxes_SNR_traces,tcur,snr_cur,'DisplayName',['ROI ', num2str(nr)],'Color',app.roicolors{nr});
                app.spikes_SNR{nr} = scatter(app.UIAxes_SNR_traces, tcur(t_s_cur(nr,:)),snr_cur(t_s_cur(nr,:)),'filled','MarkerFaceColor',app.roicolors{nr},'MarkerEdgeColor','k');
                % % temporarily, to check calculations/underestimation
                % app.spikes_SNR_forscatterplot{nr} = scatter(app.UIAxes_SNR_AP, nr*ones(1,nnz(t_s_cur(nr,:))), snr_cur(t_s_cur(nr,:)),'k');
           end
           
           
           hold(app.UIAxes_highpass_traces,'on');
           for nr = 1:NR
                hp_cur = app.F_proc.F_hp(nr,app.dat.params.k_valid,1);
                app.lines_hp{nr} = plot(app.UIAxes_highpass_traces, tcur,hp_cur,'DisplayName',['ROI ', num2str(nr)],'Color',app.roicolors{nr});
                app.spikes_hp{nr} = scatter(app.UIAxes_highpass_traces,tcur(t_s_cur(nr,:)),hp_cur(t_s_cur(nr,:)),'filled','MarkerFaceColor',app.roicolors{nr},'MarkerEdgeColor','k');
           end

           hold(app.UIAxes_Fdet,'on');
           for nr = 1:NR
               F_det_cur = app.F_proc.F_det(nr,app.dat.params.k_valid);
                app.lines_det{nr} = plot(app.UIAxes_Fdet, tcur,F_det_cur,'DisplayName',['ROI ', num2str(nr)],'Color',app.roicolors{nr});
                app.spikes_det{nr} = scatter(app.UIAxes_Fdet,tcur(t_s_cur(nr,:)),F_det_cur(t_s_cur(nr,:)),'filled','MarkerFaceColor',app.roicolors{nr},'MarkerEdgeColor','k');
           end
               
           recalculateDeltaFFButtonPushed(app)

           ROIsListBoxValueChanged(app)

            app.SaveanalysisButton.FontColor = 'k';

        end

        % Value changed function: SpikesSwitch
        function SpikesSwitchValueChanged(app, event)
            value = app.SpikesSwitch.ValueIndex;
            NR = size(app.dat.rois.roimat,3);
            opts = {'off','on'};
            
            k_r = ismember(app.ROIsListBox.Items, app.ROIsListBox.Value);

            for nr = 1:NR
                app.spikes_hp{nr}.Visible = opts{max(1,value*k_r(nr))};
                app.spikes_SNR{nr}.Visible = opts{max(1,value*k_r(nr))};
                app.spikes_det{nr}.Visible = opts{max(1,value*k_r(nr))};
                app.spikes_est_dff{nr}.Visible = opts{max(1,value*k_r(nr))};
            end
        end

        % Button pushed function: RestoreintervalButton
        function RestoreintervalButtonPushed(app, event)
            [~, selectedIndices] = select1ExistingInterval(app.UIAxes_Fraw, app.shadings, app.t_intervals);
            app.t_intervals(selectedIndices,:) = [];
            delete(app.shadings(selectedIndices));
            delete(app.shading_lines(selectedIndices,:));
            app.shadings(selectedIndices) = [];
            app.shading_lines(selectedIndices) = [];
        end

        % Button pushed function: SaveanalysisButton
        function SaveanalysisButtonPushed(app, event)
            datadir = app.datadir;
            F_proc = app.F_proc;
            dff_AP = app.dat.dff_AP;
            snr_AP = app.dat.snr_AP;
            roi_use = find(ismember(app.ROIsListBox.Items,app.ROIsListBox.Value));
            k_valid = app.dat.params.k_valid;
            tvec_valid = app.dat.tvec(k_valid);
            pixcount = app.dat.pixcount;
            cell_phot_singleframe = app.dat.cell_phot_singleframe;

            savedir_cur = strrep(strrep(app.titlestr,'/','_'),'\','_');

            nfile = 1;
            while exist(fullfile(app.savedir,savedir_cur,['analysis_',num2str(nfile)]),'dir')
                nfile = nfile +1;
            end
       
            folderstr = ['analysis_',num2str(nfile)];
            mkdir(fullfile(app.savedir,savedir_cur,folderstr))
            save(fullfile(app.savedir,savedir_cur,folderstr,'analysis.mat'),'F_proc','roi_use','datadir','tvec_valid','dff_AP','snr_AP','k_valid','cell_phot_singleframe','pixcount')
            
            app.SaveanalysisButton.FontColor = 'g';

        end

        % Value changed function: viewtimeintervalSlider
        function viewtimeintervalSliderValueChanged(app, event)
            xlims = app.viewtimeintervalSlider.Value;
            
            app.UIAxes_highpass_traces.XLim = xlims;
            app.UIAxes_SNR_traces.XLim = xlims;
            app.UIAxes_Fdet.XLim = xlims;
            app.UIAxes_Fraw.XLim = xlims;
            app.UIAxes_estimate_DFF.XLim = xlims;
        end

        % Button pushed function: recalculateDeltaFFButton
        function recalculateDeltaFFButtonPushed(app, event)
            cla(app.UIAxes_estimate_DFF)
            hold(app.UIAxes_estimate_DFF,'on');

            t_lims_cur = app.viewtimeintervalSlider.Value;

            % for each ROI, remove spikes and calculate baseline based on
            % window 
            t_vec_cur = app.dat.tvec;
            Lt = length(t_vec_cur);
            t_s_cur = app.F_proc.t_s;


             % calculate DF/F and plot
            for nr = 1:size(app.dat.rois.roimat,3)
                F_cur = app.dat.signal.tr(nr,:);
                vec_use = true(1,Lt);
                for ns = find(t_s_cur(nr,:))
                    vec_use(max(1, ns-2):min(Lt, ns+2)) = false ;
                end
                F_cur(~app.dat.params.k_valid) = NaN;
                F_cur_nospikes = F_cur;
                F_cur_nospikes(~vec_use) = NaN;
                
                F0_cur = movmean(F_cur_nospikes, (app.dff_window*app.dat.params.fs),'omitnan');
                DFF_cur = -(F_cur-F0_cur)./F0_cur;
                app.lines_est_dff{nr} = plot(app.UIAxes_estimate_DFF, t_vec_cur,DFF_cur,'DisplayName',['ROI ', num2str(nr)],'Color',app.roicolors{nr});
                app.spikes_est_dff{nr} = scatter(app.UIAxes_estimate_DFF, t_vec_cur(t_s_cur(nr,:)),DFF_cur(t_s_cur(nr,:)),'filled','MarkerFaceColor',app.roicolors{nr},'MarkerEdgeColor','k');
                % temporarily, to check calculations/underestimation
                % app.spikes_DFF_forscatterplot{nr} = scatter(app.UIAxes_DFF_AP, nr*ones(1,nnz(t_s_cur(nr,:))), DFF_cur(t_s_cur(nr,:)),'k');
    
            end

            SpikesSwitchValueChanged(app);
            ROIsListBoxValueChanged(app);

            xlim(app.UIAxes_estimate_DFF, t_lims_cur)
        end

        % Value changed function: windowsecondsEditField
        function windowsecondsEditFieldValueChanged(app, event)
            app.dff_window = app.windowsecondsEditField.Value;
            
        end

        % Button pushed function: ScreenshotButton
        function ScreenshotButtonPushed(app, event)
    
            % Build a timestamped filename in the user's current folder
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');  %#ok<DATST>
            filename  = sprintf('VoltAppScreenshot_%s_%s.png', strrep(app.titlestr,'/','_'), timestamp);
    
            % Optionally let the user choose the save location
            [file, path] = uiputfile( ...
                {'*.png','PNG Image'; '*.jpg','JPEG Image'; ...
                 '*.tif','TIFF Image'; '*.pdf','PDF Document'}, ...
                'Save Screenshot As', filename);
    
            if isequal(file, 0)
                return;  % User cancelled
            end
    
            fullPath = fullfile(path, file);
            exportgraphics(app.VoltAppUIFigure, fullPath, 'Resolution', 150);
    
            % uialert(app.VoltAppUIFigure, ...
            %     sprintf('Screenshot saved:\n%s', fullPath), ...
            %     'Screenshot Saved', 'Icon', 'success');         
    
        end

        % Selection changed function: HighpassButtonGroup
        function HighpassButtonGroupSelectionChanged(app, event)
            selectedButton = app.HighpassButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text(1:4),'fast'))
                app.highpass_movfilter = 1;
            else
                app.highpass_movfilter = 0;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoltAppUIFigure = uifigure('Visible', 'off');
            app.VoltAppUIFigure.Name = 'VoltApp';
            app.UIAxes_Fraw = uiaxes(app.VoltAppUIFigure);
            title(app.UIAxes_Fraw, 'Raw F traces')
            xlabel(app.UIAxes_Fraw, 'time (s)')
            ylabel(app.UIAxes_Fraw, '-F')
            zlabel(app.UIAxes_Fraw, 'Z')
            app.UIAxes_Fraw.Visible = 'off';
            app.UIAxes_image = uiaxes(app.VoltAppUIFigure);
            zlabel(app.UIAxes_image, 'Z')
            app.UIAxes_image.Visible = 'off';
            app.UIAxes_SNR_traces = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_SNR_traces, 'time (s)')
            ylabel(app.UIAxes_SNR_traces, 'SNR')
            zlabel(app.UIAxes_SNR_traces, 'Z')
            app.UIAxes_SNR_traces.Visible = 'off';
            app.UIAxes_SNR_AP = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_SNR_AP, 'ROI')
            ylabel(app.UIAxes_SNR_AP, 'SNR')
            zlabel(app.UIAxes_SNR_AP, 'Z')
            app.UIAxes_SNR_AP.Visible = 'off';
            app.UIAxes_highpass_traces = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_highpass_traces, 'time (s)')
            ylabel(app.UIAxes_highpass_traces, '-F (highpass)')
            zlabel(app.UIAxes_highpass_traces, 'Z')
            app.UIAxes_highpass_traces.Visible = 'off';
            app.UIAxes_DFF_AP = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_DFF_AP, 'ROI')
            ylabel(app.UIAxes_DFF_AP, '-\DeltaF/F')
            zlabel(app.UIAxes_DFF_AP, 'Z')
            app.UIAxes_DFF_AP.Visible = 'off';
            app.UIAxes_Fdet = uiaxes(app.VoltAppUIFigure);
            title(app.UIAxes_Fdet, 'detrended F')
            xlabel(app.UIAxes_Fdet, 'time (s)')
            ylabel(app.UIAxes_Fdet, '-F')
            zlabel(app.UIAxes_Fdet, 'Z')
            app.UIAxes_Fdet.Visible = 'off';
            app.UIAxes_estimate_DFF = uiaxes(app.VoltAppUIFigure);
            xlabel(app.UIAxes_estimate_DFF, 'time (s)')
            ylabel(app.UIAxes_estimate_DFF, '-\DeltaF/F')
            zlabel(app.UIAxes_estimate_DFF, 'Z')
            app.UIAxes_estimate_DFF.Visible = 'off';
            app.SaveanalysisButton = uibutton(app.VoltAppUIFigure, 'push');
            app.SaveanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveanalysisButtonPushed, true);
            app.SaveanalysisButton.Visible = 'off';
            app.SaveanalysisButton.Text = 'Save analysis';
            app.FindfolderButton = uibutton(app.VoltAppUIFigure, 'push');
            app.FindfolderButton.ButtonPushedFcn = createCallbackFcn(app, @FindfolderButtonPushed, true);
            app.FindfolderButton.Text = 'Find folder';
            app.LoadButton = uibutton(app.VoltAppUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.VoltAppUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AddintervaltoignoreButton = uibutton(app.VoltAppUIFigure, 'push');
            app.AddintervaltoignoreButton.ButtonPushedFcn = createCallbackFcn(app, @AddintervaltoignoreButtonPushed, true);
            app.AddintervaltoignoreButton.Visible = 'off';
            app.AddintervaltoignoreButton.Text = 'Add interval to ignore';
            app.RunanalysisButton = uibutton(app.VoltAppUIFigure, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Visible = 'off';
            app.RunanalysisButton.Text = 'Run analysis';
            app.RestoreintervalButton = uibutton(app.VoltAppUIFigure, 'push');
            app.RestoreintervalButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreintervalButtonPushed, true);
            app.RestoreintervalButton.Visible = 'off';
            app.RestoreintervalButton.Text = 'Restore interval';
            app.FolderpathEditFieldLabel = uilabel(app.VoltAppUIFigure);
            app.FolderpathEditFieldLabel.Text = 'Folder path';
            app.FolderpathEditField = uieditfield(app.VoltAppUIFigure, 'text');
            app.FolderpathEditField.ValueChangedFcn = createCallbackFcn(app, @FolderpathEditFieldValueChanged, true);
            app.ROIsListBoxLabel = uilabel(app.VoltAppUIFigure);
            app.ROIsListBoxLabel.Visible = 'off';
            app.ROIsListBoxLabel.Text = 'ROIs';
            app.ROIsListBox = uilistbox(app.VoltAppUIFigure);
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Visible = 'off';
            app.ROIsListBox.Value = {'Item 1'};
            app.Panel = uipanel(app.VoltAppUIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.SpikesSwitch = uiswitch(app.Panel, 'toggle');
            app.SpikesSwitch.Items = {'Spikes off ', 'Spikes on'};
            app.SpikesSwitch.ValueChangedFcn = createCallbackFcn(app, @SpikesSwitchValueChanged, true);
            app.SpikesSwitch.Value = 'Spikes off ';
            app.viewtimeintervalSlider = uislider(app.VoltAppUIFigure, 'range');
            app.viewtimeintervalSlider.Limits = [0 1];
            app.viewtimeintervalSlider.ValueChangedFcn = createCallbackFcn(app, @viewtimeintervalSliderValueChanged, true);
            app.viewtimeintervalSlider.Step = 0.01;
            app.viewtimeintervalSlider.Value = [0 1];
            app.recalculateDeltaFFButton = uibutton(app.VoltAppUIFigure, 'push');
            app.recalculateDeltaFFButton.ButtonPushedFcn = createCallbackFcn(app, @recalculateDeltaFFButtonPushed, true);
            app.recalculateDeltaFFButton.Interpreter = 'tex';
            app.recalculateDeltaFFButton.Text = 'recalculate \DeltaF/F';
            app.windowsecondsEditFieldLabel = uilabel(app.VoltAppUIFigure);
            app.windowsecondsEditFieldLabel.Visible = 'off';
            app.windowsecondsEditFieldLabel.Text = 'window (seconds)';
            app.windowsecondsEditField = uieditfield(app.VoltAppUIFigure, 'numeric');
            app.windowsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @windowsecondsEditFieldValueChanged, true);
            app.windowsecondsEditField.Visible = 'off';
            app.windowsecondsEditField.Value = 3;
            app.ScreenshotButton = uibutton(app.VoltAppUIFigure, 'push');
            app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
            app.ScreenshotButton.Visible = 'off';
            app.ScreenshotButton.Text = 'Screenshot';
            app.HighpassButtonGroup = uibuttongroup(app.VoltAppUIFigure);
            app.HighpassButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HighpassButtonGroupSelectionChanged, true);
            app.HighpassButtonGroup.Title = 'Highpass';
            app.highpassButton = uiradiobutton(app.HighpassButtonGroup);
            app.highpassButton.Text = 'highpass()';
            app.highpassButton.Value = true;
            app.fastsmoothButton = uiradiobutton(app.HighpassButtonGroup);
            app.fastsmoothButton.Text = 'fastsmooth()';
            % Show the figure after all components are created
            app.VoltAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volt_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoltAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoltAppUIFigure)
        end
    end
end